classdef NewMint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        StepsSlider               matlab.ui.control.Slider
        StepsSliderLabel          matlab.ui.control.Label
        SelectSumTypeButtonGroup  matlab.ui.container.ButtonGroup
        Button4                   matlab.ui.control.ToggleButton
        Button5                   matlab.ui.control.ToggleButton
        MButton                   matlab.ui.control.ToggleButton
        LButton                   matlab.ui.control.ToggleButton
        RButton                   matlab.ui.control.ToggleButton
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        gxEditField               matlab.ui.control.EditField
        gxEditFieldLabel          matlab.ui.control.Label
        fxEditField               matlab.ui.control.EditField
        fxEditFieldLabel          matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        SumErrorLabel             matlab.ui.control.Label
        SumLabel                  matlab.ui.control.Label
        IntegralLabel             matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Define x as symbolic variable
        x = sym("x")
        % Most up to date function variables -- direct user input
        newFx = symfun(0, sym("x"));
        newGx = symfun(0, sym("x"));
        % Slightly outdated function variables -- updated after anim.
        currentFx = symfun(0, sym("x"));
        currentGx = symfun(0, sym("x"));
        
        % Last input stored as a serial date number to avoid animation
        % problems from input spam
        fxLastIn;
        gxLastIn;

        % References to plotted fx and gx
        fxPlot;
        gxPlot; 

        % # steps for numeric integration
        steps = 0;

        % Number of points to be set during animation
        animationPoints;

        % Number of frames in animation
        animationFrames;
    end
    
    methods (Access = private)
        
        function shouldRedraw(app)
            %% Check if input function changed
            if app.newFx ~= app.currentFx
                app.fxLastIn = now;
                inputLimiter(app, 'f');
            elseif app.newGx ~= app.currentGx
                app.gxLastIn = now;
                inputLimiter(app, 'g');
            end
        end
        
        function redraw(app, currentFunction)
            % Store current screen bounds so screen doesn't move during anim
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;
            % Subdivide screen into n steps with n = visualQuality
            xSteps = linspace(xLimits(1), xLimits(2), app.animationPoints);

            % Preallocate operating/static function arrays
            operatingFunction = zeros(app.animationFrames, app.animationPoints);
            staticFunction = zeros(1, app.animationPoints);

            %% Set matrices for animated & static variables
            if currentFunction == 'f'
                for n = 1:1:app.animationFrames
                    % Calculate interp value at current iteration
                    intVal = n / app.animationFrames;
                    intFunc = intVal^2 * (3 - 2 * intVal);
                    % Set the state of the animated function for this frame
                    operatingFunction(n, :) = (1 - intFunc) * app.currentFx(xSteps) + intFunc * app.newFx(xSteps);
                end
                % As static fn doesn't move, it only needs to be set once
                staticFunction = app.currentGx(xSteps);
            else % See above
                for n = 1:1:app.animationFrames
                    intVal = n / app.animationFrames;
                    intFunc = intVal^2 * (3 - 2 * intVal);
                    operatingFunction(n, :) = (1 - intFunc) * app.currentGx(xSteps) + intFunc * app.newGx(xSteps);
                end
                staticFunction = app.currentFx(xSteps);
            end

            %% TODO: IF IMAGINARY/COMPLEX, **REMOVE TERM**
            %% Run the animation
            if currentFunction == 'f'
                for n = 1:1:app.animationFrames
                    % Plot this frame using precalculated arrays
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes, xSteps, operatingFunction(n, :));
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, xSteps, staticFunction);
                    plotAxes(app)
                    % Reset the limits so screen doesn't move
                    app.UIAxes.XLim = xLimits;
                    app.UIAxes.YLim = yLimits;
                    % Frame delay
                    pause(0.005)
                end
                % Update stored function
                app.currentFx = app.newFx;
            else % See above
                for n = 1:1:app.animationFrames
                    % Order swapped to maintain colors
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes, xSteps, staticFunction);
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, xSteps, operatingFunction(n, :));
                    plotAxes(app)
                    pause(0.005)
                end
                app.currentGx = app.newGx;
            end

            % Plot new functions in full quality
            hold(app.UIAxes, "off")
            app.fxPlot = fplot(app.UIAxes, app.currentFx);
            hold(app.UIAxes, "on")
            app.gxPlot = fplot(app.UIAxes, app.currentGx);
            plotAxes(app)
        end
        
        function inputLimiter(app, funcIn)
            pause(0.5)
            if funcIn == 'f' && now > app.fxLastIn + 0.000005
               redraw(app, funcIn);
            elseif funcIn == 'g' && now > app.gxLastIn + 0.000005
             redraw(app, funcIn);
            end
        end
        
        function plotAxes(app)
            xline(app.UIAxes, 0, "Color", "#AAAAAA")
            yline(app.UIAxes, 0, "Color", "#AAAAAA")
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set bounds and plot axes
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            plotAxes(app)

            % Check if user has valid GPU and accomodate graphics
            try
                gpuDevice;
                app.animationFrames = 20;
                app.animationPoints = 50;
            catch
                app.animationFrames = 5;
                app.animationPoints = 50;
            end
        end

        % Value changing function: fxEditField
        function fxEditFieldValueChanging(app, event)
            changingValue = event.Value;
            % If function is deleted, reset its graph to y = 0
            if isempty(changingValue)
                changingValue = "0";
            end

            try
                app.newFx = symfun(str2sym(changingValue), app.x);
                app.fxPlot.Color = "#0072BD";
                shouldRedraw(app);
            catch
                %% TODO: Figure out why this sometimes returns "invalid or deleted object" and fix rather than this bandaid
                try
                app.fxPlot.Color = "#AAAAAA";
                catch
                end
            end
        end

        % Value changing function: gxEditField
        function gxEditFieldValueChanging(app, event)
            changingValue = event.Value;

            if isempty(changingValue)
                changingValue = "0";
            end

            try
                app.newGx = symfun(str2sym(changingValue), app.x);
                app.gxPlot.Color = "#D95319";
                shouldRedraw(app);
            catch
                try
                app.gxPlot.Color = "#AAAAAA";
                catch
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {331, 331};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.fxEditFieldLabel = uilabel(app.LeftPanel);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.LeftPanel, 'text');
            app.fxEditField.ValueChangingFcn = createCallbackFcn(app, @fxEditFieldValueChanging, true);
            app.fxEditField.Placeholder = 'Enter f(x)';
            app.gxEditFieldLabel = uilabel(app.LeftPanel);
            app.gxEditFieldLabel.Text = 'g(x)';
            app.gxEditField = uieditfield(app.LeftPanel, 'text');
            app.gxEditField.ValueChangingFcn = createCallbackFcn(app, @gxEditFieldValueChanging, true);
            app.gxEditField.Placeholder = 'Enter g(x)';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SelectSumTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectSumTypeButtonGroup.Title = 'Select Sum Type';
            app.RButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.RButton.Text = 'R';
            app.RButton.Value = true;
            app.LButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.LButton.Text = 'L';
            app.MButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.MButton.Text = 'M';
            app.Button5 = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.Button5.Text = 'Tr';
            app.Button4 = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.Button4.Text = 'Si';
            app.StepsSliderLabel = uilabel(app.LeftPanel);
            app.StepsSliderLabel.Text = '# Steps';
            app.StepsSlider = uislider(app.LeftPanel);
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'GRAPH')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IntegralLabel = uilabel(app.RightPanel);
            app.IntegralLabel.Text = 'Integral';
            app.SumLabel = uilabel(app.RightPanel);
            app.SumLabel.Text = '___ Sum';
            app.SumErrorLabel = uilabel(app.RightPanel);
            app.SumErrorLabel.Text = 'Sum Error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewMint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end